classdef SAR_Clutter < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        AirbornClutterSimUIFigure  matlab.ui.Figure
        GridLayout                 matlab.ui.container.GridLayout
        LeftPanel                  matlab.ui.container.Panel
        Label_7                    matlab.ui.control.Label
        Lamp                       matlab.ui.control.Lamp
        TabGroup                   matlab.ui.container.TabGroup
        SARTab                     matlab.ui.container.Tab
        GridLayout4                matlab.ui.container.GridLayout
        DropDown_2                 matlab.ui.control.DropDown
        Label_8                    matlab.ui.control.Label
        Button_2                   matlab.ui.control.Button
        Button                     matlab.ui.control.Button
        CheckBox                   matlab.ui.control.CheckBox
        DropDown                   matlab.ui.control.DropDown
        Label_6                    matlab.ui.control.Label
        Label_5                    matlab.ui.control.Label
        PRFHzEditField             matlab.ui.control.NumericEditField
        PRFHzEditFieldLabel        matlab.ui.control.Label
        dBEditField                matlab.ui.control.NumericEditField
        dBEditFieldLabel           matlab.ui.control.Label
        kWEditField                matlab.ui.control.NumericEditField
        kWEditFieldLabel           matlab.ui.control.Label
        msEditField                matlab.ui.control.NumericEditField
        Label_4                    matlab.ui.control.Label
        mEditField                 matlab.ui.control.NumericEditField
        Label_3                    matlab.ui.control.Label
        Label_2                    matlab.ui.control.Label
        usEditField                matlab.ui.control.NumericEditField
        usEditFieldLabel           matlab.ui.control.Label
        MHzEditField               matlab.ui.control.NumericEditField
        MHzEditFieldLabel          matlab.ui.control.Label
        GHzEditField               matlab.ui.control.NumericEditField
        GHzEditFieldLabel          matlab.ui.control.Label
        Label                      matlab.ui.control.Label
        GMTITab                    matlab.ui.container.Tab
        RightPanel                 matlab.ui.container.Panel
        GridLayout3                matlab.ui.container.GridLayout
        sarimg                     matlab.ui.control.UIAxes
        groundtruth                matlab.ui.control.UIAxes
        rawdata                    matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        freq,lambda,c,bw,fs,tpd,xLimits,yLimits,rngRes,
        initialHgt,initialPerturb,numIter,roughnessFactor,dispstr,dispmode,slcimg,minSample,
        A,xvec,yvec,x,y,apertureLength,sqa,v,dur,rdrhgt,rdrpos1,rdrvel,scenestr,gammaDB,
        rdrpos2,len,rdrplat,scene,depang,grazang,prf,rc,rdr,targetpos,saveddata,savedraw,savedimg
    end
    
    methods (Access = public)
        %% functions
        function [x,y,terrain] = RandomTerrainGenerator(~,f,initialHeight, ...
            initialPerturb,minX,maxX,minY,maxY,numIter)
        dX = (maxX-minX)/2;
        dY = (maxY-minY)/2;
        [x,y] = meshgrid(minX:dX:maxX,minY:dY:maxY);
        terrain = ones(3,3)*initialHeight;
        perturb = initialPerturb;
        for ii = 2:numIter
            perturb = perturb/f;
            oldX = x;
            oldY = y;
            dX = (maxX-minX)/2^ii;
            dY = (maxY-minY)/2^ii;
            [x,y] = meshgrid(minX:dX:maxX,minY:dY:maxY);
            terrain = griddata(oldX,oldY,terrain,x,y);
            terrain = terrain + perturb*randn(1+2^ii,1+2^ii);
            terrain(terrain < 0) = 0; 
        end
        end
        function PlotSLC(app, slcimg, minSample, fs, v, prf, rdrpos1)
                % Plot magnitude of focused SAR image in app.sarimg
                
                % Cross-range y-vector (m)
                numPulses = size(slcimg, 2); 
                du = v * 1 / prf; % Cross-range sample spacing (m) 
                dky = 2 * pi / (numPulses * du); % ku domain sample spacing (rad/m)
                dy = 2 * pi / (numPulses * dky); % y-domain sample spacing (m)
                y = dy * (0:(numPulses - 1)) + rdrpos1(2); % Cross-range y-vector (m)
                
                % Range vector (m)
                c = physconst('LightSpeed'); 
                numSamples = size(slcimg, 1); 
                samples = minSample:(numSamples + minSample - 1);
                sampleTime = samples * 1 / fs; 
                rngVec = time2range(sampleTime(1:end), c); 
                
                % 将绘图区域设置为 app.sarimg
                axes(app.sarimg); % 设置目标为 app.sarimg
                cla(app.sarimg);  % 清除之前的绘图内容
                
                % SAR Image
                slcimg = abs(slcimg).';  % 计算绝对值并转置图像
                hProc = pcolor(app.sarimg, rngVec, y, slcimg);
                hProc.EdgeColor = 'none';

                if app.dispmode
                    colormap(app.sarimg, gray); 
                else 
                    colormap(app.sarimg, "default"); 
                end
                %colormap(app.sarimg, gray);  % 使用灰度颜色映射
                
                % 设置轴标签和标题
                xlabel(app.sarimg, 'Slant Range (m)');
                ylabel(app.sarimg, 'Cross-range (m)');
                title(app.sarimg, 'SAR Image');
                
                % 设置坐标轴范围
                axis(app.sarimg, 'equal');
                xlim(app.sarimg, [1250 1420]);
                ylim(app.sarimg, [0 100]);
                
                % 刷新显示
                drawnow;
                end
                
        
                function cmap = landColormap(~,n)
                %landColormap Colormap for land surfaces
                % cmap = landColormap(n)
                %
                % Inputs: 
                %    - n     = Number of samples in colormap
                %
                % Output: 
                %    - cmap  = n-by-3 colormap
                
                c = hsv2rgb([5/12 1 0.4; 0.25 0.2 1; 5/72 1 0.4]);
                cmap = zeros(n,3);
                cmap(:,1) = interp1(1:3,c(:,1),linspace(1,3,n)); 
                cmap(:,2) = interp1(1:3,c(:,2),linspace(1,3,n));
                cmap(:,3) = interp1(1:3,c(:,3),linspace(1,3,n)); 
                colormap(cmap);
                end

            
        function PlotGroundTruth(app, xvec, yvec, A, rdrpos1, rdrpos2, targetpos)
        % Plot ground truth in the app's UIAxes (app.groundtruth)
        
        % 将绘图目标设置为app.groundtruth
        axes(app.groundtruth); % 设置绘图区域为app.groundtruth
        cla(app.groundtruth); % 清除旧的绘图
        
        % Plot boundary. Set plot boundary much lower than 0 for rendering reasons. 
        hLim = surf(app.groundtruth, [0 1500], [-200 200].', -100*ones(2), ...
            'FaceColor', [0.8 0.8 0.8], 'FaceAlpha', 0.7);
        hold(app.groundtruth, 'on');
        
        % Plot the terrain surface
        hS = surf(app.groundtruth, xvec, yvec, A);
        hS.EdgeColor = 'none';
        
        
        % Set colormap for terrain
        landmap = app.landColormap(64);
        colormap(app.groundtruth, landmap); 
        
        % Plot radar path
        hPlatPath = plot3(app.groundtruth, [rdrpos1(1) rdrpos2(1)], ...
            [rdrpos1(2) rdrpos2(2)], [rdrpos1(3) rdrpos2(3)], ...
            '-k', 'LineWidth', 2);
        
        % Plot radar start point
        hPlatStart = plot3(app.groundtruth, rdrpos1(1), rdrpos1(2), rdrpos1(3), ...
            'o', 'LineWidth', 2, 'MarkerFaceColor', 'g', 'MarkerEdgeColor', 'k');
        
        % Plot target positions
        hTgt = plot3(app.groundtruth, app.targetpos(:,1), app.targetpos(:,2), app.targetpos(:,3), ...
            'o', 'LineWidth', 2, 'MarkerFaceColor', [0.8500 0.3250 0.0980], 'MarkerEdgeColor', 'k');
        
        % Set view angle
        view(app.groundtruth, [26 75]);
        
        % Set labels and title
        xlabel(app.groundtruth, 'Range (m)');
        ylabel(app.groundtruth, 'Cross-range (m)');
        title(app.groundtruth, '仿真场景显示');
        
        % Set axis limits and legend
        axis(app.groundtruth, 'tight');
        zlim(app.groundtruth, [-100 2000]);
        
        legend(app.groundtruth, [hLim, hS, hPlatPath, hPlatStart, hTgt], ...
            {'场景边界', '成像区域', '平台路径', '雷达起始点', '目标'}, 'Location', 'SouthWest');
        
        % Draw the plot and allow the UI to update
        drawnow;
        end   
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.freq = app.GHzEditField.Value * 1e9;     % Carrier frequency (Hz)
            [app.lambda,app.c] = freq2wavelen(app.freq);   % Wavelength (m) 
            app.bw = app.MHzEditField.Value * 1e6;
            app.fs = app.bw * 2;                         % Sampling frequency (Hz)
            app.tpd = app.usEditField.Value * 1e-6;  % Pulse width (sec) 
            % Create terrain
            app.xLimits         = [900 1200]; % x-axis limits of terrain (m)
            app.yLimits         = [-200 200]; % y-axis limits of terrain (m)
            
            app.initialHgt      = 0;          % Initial height (m)
            app.initialPerturb  = 200;        % Overall height of map (m) 
            app.numIter         = 8;          % Number of iterations
            app.roughnessFactor = 2;
            % Verify that the range resolution is as expected
            app.rngRes = bw2rangeres(app.bw);

        [app.x,app.y,app.A] = app.RandomTerrainGenerator(app.roughnessFactor,app.initialHgt, ....
            app.initialPerturb,app.xLimits(1),app.xLimits(2), ...
            app.yLimits(1),app.yLimits(2),app.numIter);

        app.xvec = app.x(1,:); 
        app.yvec = app.y(:,1);
        app.dispmode = 1;
        app.gammaDB = surfacegamma('Flatland');
        % Antenna properties
        app.apertureLength = 6;                % Aperture length (m) 
        app.sqa = 0;                           % Squint angle (deg)

        % Platform properties
        app.v = 100;                           % Speed of the platform (m/s) 
        app.dur = 1;                           % Duration of flight (s) 
        app.rdrhgt = 1000;                     % Height of platform (m) 
        app.rdrpos1 = [0 0 app.rdrhgt];            % Start position of the radar (m)
        app.rdrvel = [0 app.v 0];                  % Radar plaform velocity
        app.rdrpos2 = app.rdrvel*app.dur + app.rdrpos1;    % End position of the radar (m)
        app.len = sarlen(app.v,app.dur);               % Synthetic aperture length (m)
        app.minSample = 500; % Minimum sample range

        app.slcimg = zeros(502,501);

        % Configure the target platforms in x and y


        app.targetpos = [1000,app.len/2,0]; % Target positions (m)
        tgthgts = 110; % Target height (m)
        
        [~,idxX] = min(abs(app.targetpos(1,1) - app.xvec)); 
        [~,idxY] = min(abs(app.targetpos(1,2) - app.yvec)); 
        tgthgts(1) = tgthgts(1) + app.A(idxX,idxY); 
        app.targetpos(1,3) = tgthgts; 
        
        % Set the reference slant range for the cross-range processing
        app.rc = sqrt((app.rdrhgt - mean(tgthgts))^2 + (mean(app.targetpos(:,1)))^2);
        
        % Antenna orientation
        app.depang = depressionang(app.rdrhgt,app.rc,'Flat','TargetHeight',mean(tgthgts)); % Depression angle (deg)
        app.grazang = app.depang; % Grazing angle (deg)
        
        % Azimuth resolution
        %azResolution = sarazres(app.rc,app.lambda,app.len)  % Cross-range resolution (m)
        
        
        % Determine PRF bounds 
        %[swlen,swwidth] = aperture2swath(app.rc,app.lambda,app.apertureLength,app.grazang);
        %[prfmin,prfmax] = sarprfbounds(app.v,azResolution,swlen,app.grazang);
        
        % Select a PRF within the PRF bounds
        app.prf = app.PRFHzEditField.Value ; % Pulse repetition frequency (Hz)
        
        % Create a radar scenario
        app.scene = radarScenario('UpdateRate',app.prf,'IsEarthCentered',false,'StopTime',app.dur);


        % Add platforms to the scene using the configurations previously defined 
        app.rdrplat = platform(app.scene,'Trajectory',kinematicTrajectory('Position',app.rdrpos1,'Velocity',[0 app.v 0]));
        
        rcs = rcsSignature('Pattern',5); 
        
        platform(app.scene,'Position',app.targetpos(1,:),'Signatures',{rcs});
        
        % Plot ground truth
        PlotGroundTruth(app, app.xvec, app.yvec, app.A, app.rdrpos1, app.rdrpos2, app.targetpos);
        PlotSLC(app, app.slcimg, app.minSample, app.fs, app.v, app.prf, app.rdrpos1);
        
        end

        % Button pushed function: Button
        function StartBtn(app, event)
        

        restart(app.scene);
        app.Lamp.Color = [1.00,0.00,0.00];
        % Add land surface to scene
        %app.gammaDB = surfacegamma('Flatland');
        refl = surfaceReflectivity('Land','Model','ConstantGamma', ...
            'Gamma',app.gammaDB);
        s = landSurface(app.scene,'RadarReflectivity',refl);


        % Create a radar looking to the right
        maxRange = 2500; % Maximum range for IQ collection
        mountAngles = [0 app.depang 0];
        app.rdr = radarTransceiver('MountingAngles',mountAngles,'NumRepetitions',1, ...
            'RangeLimits',[0 maxRange]);
        % Set peak power
        %rdr.Transmitter.PeakPower = 50e3; 
        app.rdr.Transmitter.PeakPower = app.kWEditField.Value * 1e3; 
        % Set receiver sample rate and noise figure
        app.rdr.Receiver.SampleRate = app.fs;
        app.rdr.Receiver.NoiseFigure = 30; 
        app.rdr.Receiver.Gain = app.dBEditField.Value;
        % Define transmit and receive antenna and corresponding parameters
        antbw = ap2beamwidth(app.apertureLength,app.lambda); 
        ant = phased.SincAntennaElement('FrequencyRange',[1e9 10e9],'Beamwidth',antbw);
        app.rdr.TransmitAntenna.Sensor = ant;
        app.rdr.TransmitAntenna.OperatingFrequency = app.freq;
        app.rdr.ReceiveAntenna.Sensor = ant;
        app.rdr.ReceiveAntenna.OperatingFrequency = app.freq;
        antennaGain = aperture2gain(app.apertureLength^2,app.lambda); 
        app.rdr.Transmitter.Gain = antennaGain;
        app.rdr.Receiver.Gain = antennaGain;
        
        
        % Configure the LFM signal of the radar
        app.rdr.Waveform = phased.LinearFMWaveform('SampleRate',app.fs,'PulseWidth',app.tpd, ...
            'PRF',app.prf,'SweepBandwidth',app.bw); 
        
        % Add radar to radar platform
        app.rdrplat.Sensors = app.rdr;


        %% Collect clutter returns with the clutterGenerator
        % clutterGenerator(scene,rdr,'Resolution',rngRes/2, ...
        %     'RangeLimit',maxRange);
        %%

        if app.CheckBox.Value 
            clutterGenerator(app.scene,app.rdr,'Resolution',app.rngRes, ...
             'RangeLimit',maxRange);
        end

        % Initialize output IQ datacube

        truncRngSamp = ceil(range2time(maxRange)*app.fs); % Limit the number of samples
        T = 1/app.prf; % Pulse repetition interval (sec)
        numPulses = app.dur/T + 1; % Number of pulses 
        raw = zeros(numel(app.minSample:truncRngSamp),numPulses); % IQ datacube 
        
        % Collect IQ 
        ii = 1; 
        hRaw = PlotRawIQ(app,raw,app.minSample);
        simulateData = true; % Change to true to simulate IQ
        if simulateData
            while advance(app.scene) %#ok<UNRCH>
                tmp = receive(app.scene); % nsamp x 1
                raw(:,ii) = tmp{1}(app.minSample:truncRngSamp);
                if mod(ii,10) == 0 % Update plot after 100 pulses
                    helperUpdatePlotRawIQ(hRaw,raw);
                end
                ii = ii + 1;
            end
        else
            %load('rawSAR.mat');
        end
        helperUpdatePlotRawIQ(hRaw,raw);
        
        
        % Generating Single Look Complex image using range migration algorithm
        app.slcimg = rangeMigrationLFM(raw,app.rdr.Waveform,app.freq,app.v,app.rc);
        PlotSLC(app, app.slcimg, app.minSample, app.fs, app.v, app.prf, app.rdrpos1);


        function hRaw = PlotRawIQ(app, raw, minSample)
        % Plot real part of raw SAR IQ in app.rawdata (UIAxes)
        
        % 将绘图区域设置为 app.rawdata
        axes(app.rawdata); % 设置目标为 app.rawdata
        cla(app.rawdata);  % 清除之前的绘图内容
        
        % 获取数据大小
        [m, n] = size(raw);
        
        % 使用 pcolor 绘制原始数据
        hRaw = pcolor(app.rawdata, minSample:(m + minSample - 1), 1:n, real(raw.'));
        hRaw.EdgeColor = 'none';
        
        clim([-0.06 0.06]);
        
        % 设置标题、标签和颜色条
        title(app.rawdata, 'Raw Data');
        xlabel(app.rawdata, 'Range Samples');
        ylabel(app.rawdata, 'Cross-range Samples');
        
        
        % 绘制并刷新显示
        drawnow;
        end

        
        app.savedraw = raw;
        app.savedimg = app.slcimg;
        
        app.Lamp.Color = [0.00,1.00,0.00];
        
        function helperUpdatePlotRawIQ(hRaw,raw)
        % Update the raw SAR IQ plot
        
        hRaw.CData = real(raw.'); 
        clim([-0.06 0.06]);
        drawnow
        pause(0.25)
        end


        end

        % Value changed function: GHzEditField
        function GhzChanged(app, event)
            app.freq = app.GHzEditField.Value * 1e9;

        end

        % Value changed function: MHzEditField
        function bwchanged(app, event)
            app.bw = app.MHzEditField.Value * 1e6;
            
        end

        % Value changed function: usEditField
        function tpdchanged(app, event)
            app.tpd = app.usEditField.Value * 1e-6;
            
        end

        % Value changed function: PRFHzEditField
        function prfchanged(app, event)
            app.prf = app.PRFHzEditField.Value;
            
        end

        % Value changed function: kWEditField
        function powchanged(app, event)
            app.rdr.Transmitter.PeakPower = app.kWEditField.Value * 1e3;
            
        end

        % Value changed function: dBEditField
        function dBchanged(app, event)
            app.rdr.Receiver.Gain = app.dBEditField.Value;
            
        end

        % Value changed function: mEditField
        function hightchanged(app, event)
            app.rdrhgt = app.mEditField.Value;
            app.rdrpos1 = [0 0 app.rdrhgt];            % Start position of the radar (m)
            app.rdrvel = [0 app.v 0];                  % Radar plaform velocity
            app.rdrpos2 = app.rdrvel*app.dur + app.rdrpos1;    % End position of the radar (m)
            PlotGroundTruth(app, app.xvec, app.yvec, app.A, app.rdrpos1, app.rdrpos2, app.targetpos);
            
        end

        % Button pushed function: Button_2
        function savebtn(app, event)
        app.saveddata.raw = app.savedraw;
        app.saveddata.slcimg = app.savedimg;
        tosave = app.saveddata;
        % 让用户选择保存文件的位置和名称
        [file, path] = uiputfile('*.mat', 'Save Data As');
        
        % 检查用户是否取消了文件保存操作
        if isequal(file, 0) || isequal(path, 0)
            disp('User canceled the file save operation');
            return;
        end
        
        % 构建保存文件的完整路径
        fullFileName = fullfile(path, file);
        
        % 保存 app.Data 到 .mat 文件
        try
            save(fullFileName, 'tosave');  
            disp(['Data saved successfully to: ', fullFileName]);
        catch ME
            % 处理保存数据时可能发生的错误
            disp(['Error saving data: ', ME.message]);
        end

        end

        % Value changed function: DropDown
        function scenechanged(app, event)
        PlotGroundTruth(app, app.xvec, app.yvec, app.A, app.rdrpos1, app.rdrpos2, app.targetpos);
        app.scenestr = app.DropDown.Value;
        %disp(app.scenestr);
        switch app.scenestr
            case '平原'
                app.gammaDB = surfacegamma('Flatland');  % 平原情况
            case '山区'
                app.gammaDB = surfacegamma('Rugged Mountain');  % 山地情况
            case '城市'
                app.gammaDB = surfacegamma('Metropolitan');  % 城市情况
        end
        end

        % Value changed function: DropDown_2
        function dispchanged(app, event)
            app.dispstr = app.DropDown_2.Value;
            PlotSLC(app, app.slcimg, app.minSample, app.fs, app.v, app.prf, app.rdrpos1);
            switch app.dispstr
            case '灰度'
                app.dispmode = 0; 
            case '彩色'
                app.dispmode = 1;
            end
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.AirbornClutterSimUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {630, 630};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {195, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.AirbornClutterSimUIFigure = uifigure('Visible', 'off');
            app.AirbornClutterSimUIFigure.Name = 'Airborn Clutter Sim';
            app.AirbornClutterSimUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.AirbornClutterSimUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.TabGroup = uitabgroup(app.LeftPanel);
            app.SARTab = uitab(app.TabGroup);
            app.SARTab.Title = 'SAR';
            app.GridLayout4 = uigridlayout(app.SARTab);
            app.Label = uilabel(app.GridLayout4);
            app.Label.Text = '雷达参数设置';
            app.GHzEditFieldLabel = uilabel(app.GridLayout4);
            app.GHzEditFieldLabel.Text = '载波频率(GHz)';
            app.GHzEditField = uieditfield(app.GridLayout4, 'numeric');
            app.GHzEditField.Limits = [1 2];
            app.GHzEditField.ValueChangedFcn = createCallbackFcn(app, @GhzChanged, true);
            app.GHzEditField.Value = 1;
            app.MHzEditFieldLabel = uilabel(app.GridLayout4);
            app.MHzEditFieldLabel.Text = '带宽(MHz)';
            app.MHzEditField = uieditfield(app.GridLayout4, 'numeric');
            app.MHzEditField.Limits = [30 50];
            app.MHzEditField.ValueChangedFcn = createCallbackFcn(app, @bwchanged, true);
            app.MHzEditField.Value = 30;
            app.usEditFieldLabel = uilabel(app.GridLayout4);
            app.usEditFieldLabel.Text = '脉冲宽度(us)';
            app.usEditField = uieditfield(app.GridLayout4, 'numeric');
            app.usEditField.Limits = [3 5];
            app.usEditField.ValueChangedFcn = createCallbackFcn(app, @tpdchanged, true);
            app.usEditField.Value = 3;
            app.Label_2 = uilabel(app.GridLayout4);
            app.Label_2.Text = '平台参数设置';
            app.Label_3 = uilabel(app.GridLayout4);
            app.Label_3.Text = '高度(m)';
            app.mEditField = uieditfield(app.GridLayout4, 'numeric');
            app.mEditField.Limits = [800 1500];
            app.mEditField.ValueChangedFcn = createCallbackFcn(app, @hightchanged, true);
            app.mEditField.Value = 1000;
            app.Label_4 = uilabel(app.GridLayout4);
            app.Label_4.Text = '速度(m/s)';
            app.msEditField = uieditfield(app.GridLayout4, 'numeric');
            app.msEditField.Limits = [100 200];
            app.msEditField.Value = 100;
            app.kWEditFieldLabel = uilabel(app.GridLayout4);
            app.kWEditFieldLabel.Text = '发射功率(kW)';
            app.kWEditField = uieditfield(app.GridLayout4, 'numeric');
            app.kWEditField.ValueChangedFcn = createCallbackFcn(app, @powchanged, true);
            app.kWEditField.Value = 50;
            app.dBEditFieldLabel = uilabel(app.GridLayout4);
            app.dBEditFieldLabel.Text = '天线增益(dB)';
            app.dBEditField = uieditfield(app.GridLayout4, 'numeric');
            app.dBEditField.ValueChangedFcn = createCallbackFcn(app, @dBchanged, true);
            app.dBEditField.Value = 20;
            app.PRFHzEditFieldLabel = uilabel(app.GridLayout4);
            app.PRFHzEditFieldLabel.Text = 'PRF(Hz)';
            app.PRFHzEditField = uieditfield(app.GridLayout4, 'numeric');
            app.PRFHzEditField.Limits = [400 600];
            app.PRFHzEditField.ValueChangedFcn = createCallbackFcn(app, @prfchanged, true);
            app.PRFHzEditField.Value = 500;
            app.Label_5 = uilabel(app.GridLayout4);
            app.Label_5.Text = '场景与杂波';
            app.Label_6 = uilabel(app.GridLayout4);
            app.Label_6.Text = '场景类型';
            app.DropDown = uidropdown(app.GridLayout4);
            app.DropDown.Items = {'平原', '山区', '城市'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @scenechanged, true);
            app.DropDown.Value = '平原';
            app.CheckBox = uicheckbox(app.GridLayout4);
            app.CheckBox.Text = '杂波仿真';
            app.Button = uibutton(app.GridLayout4, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @StartBtn, true);
            app.Button.Text = '开始仿真';
            app.Button_2 = uibutton(app.GridLayout4, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @savebtn, true);
            app.Button_2.Text = '保存结果';
            app.Label_8 = uilabel(app.GridLayout4);
            app.Label_8.Text = '成像显示';
            app.DropDown_2 = uidropdown(app.GridLayout4);
            app.DropDown_2.Items = {'灰度', '彩色'};
            app.DropDown_2.ValueChangedFcn = createCallbackFcn(app, @dispchanged, true);
            app.DropDown_2.Value = '灰度';
            app.GMTITab = uitab(app.TabGroup);
            app.GMTITab.Title = 'GMTI';
            app.Lamp = uilamp(app.LeftPanel);
            app.Label_7 = uilabel(app.LeftPanel);
            app.Label_7.Text = '运行状态';
            app.RightPanel = uipanel(app.GridLayout);
            app.GridLayout3 = uigridlayout(app.RightPanel);
            app.rawdata = uiaxes(app.GridLayout3);
            title(app.rawdata, '原始回波数据')
            app.rawdata.XLimitMethod = 'tight';
            app.rawdata.YLimitMethod = 'tight';
            app.rawdata.ZLimitMethod = 'tight';
            app.rawdata.XTick = [];
            app.rawdata.YTick = [];
            colormap(app.rawdata, 'parula')
            app.groundtruth = uiaxes(app.GridLayout3);
            title(app.groundtruth, '仿真场景')
            app.groundtruth.XTick = [];
            app.groundtruth.YTick = [];
            colormap(app.groundtruth, 'parula')
            app.sarimg = uiaxes(app.GridLayout3);
            title(app.sarimg, 'SAR成像结果')
            app.sarimg.XTick = [];
            app.sarimg.YTick = [];
            colormap(app.sarimg, 'parula')
            % Show the figure after all components are created
            app.AirbornClutterSimUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SAR_Clutter

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.AirbornClutterSimUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.AirbornClutterSimUIFigure)
        end
    end
end